classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        IQtoolsButton            matlab.ui.control.Button
        CutterCheckBox           matlab.ui.control.CheckBox
        BeamformingCheckBox      matlab.ui.control.CheckBox
        ChannelselectListBox     matlab.ui.control.ListBox
        ChannelselectLabel       matlab.ui.control.Label
        CutoffsetEditField       matlab.ui.control.NumericEditField
        CutoffsetEditFieldLabel  matlab.ui.control.Label
        GapEditField             matlab.ui.control.NumericEditField
        GapEditFieldLabel        matlab.ui.control.Label
        VSACheckBox              matlab.ui.control.CheckBox
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Property % Description
    end

    methods (Access = public)
        function [vsa, ch, bf, off, gap, cutter] = chkParams(app)
            vsa = app.VSACheckBox.Value;
            ch = str2num(app.ChannelselectListBox.Value);
            bf = app.BeamformingCheckBox.Value;
            cutter = app.CutterCheckBox.Value;
            off = app.CutoffsetEditField.Value;
            gap = app.GapEditField.Value;
        end
    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ang_num = 1;
            num = 1;
            setupFile = 'ofdm_iq_20_cal.setx';
            dataChan = 2^14;

            [data_v, estimator, tcp_client, plot_handle] = rfsocBfPrep(app, dataChan, setupFile, num);
            while true
                [vsa, ch, bf, off, gap, cutter] = chkParams(app);
                rfsocBf(app, vsa, ch, bf, off, gap, cutter, ang_num, estimator, data_v, tcp_client, plot_handle);
            end
        end

        % Value changed function: VSACheckBox
        function VSACheckBoxValueChanged(app, event)
            vsa = app.VSACheckBox.Value;
        end

        % Callback function
        function BeamformingButtonGroupSizeChanged(app, event)
            position = app.BeamformingButtonGroup.Position;

        end

        % Value changed function: BeamformingCheckBox
        function BeamformingCheckBoxValueChanged(app, event)
            bf = app.BeamformingCheckBox.Value;

        end

        % Value changed function: ChannelselectListBox
        function ChannelselectListBoxValueChanged(app, event)
            ch = app.ChannelselectListBox.Value;

        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function UIAxesButtonDown2(app, event)
            %             clf(app.UIAxes);
        end

        % Button pushed function: IQtoolsButton
        function IQtoolsButtonPushed(app, event)
            if exist('..\iqtools_2023_10_24')
                iqtools
            else
                addpath('..\iqtools_2023_10_24')
                iqtools
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {145, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VSACheckBox = uicheckbox(app.LeftPanel);
            app.VSACheckBox.ValueChangedFcn = createCallbackFcn(app, @VSACheckBoxValueChanged, true);
            app.VSACheckBox.Text = 'VSA';
            app.VSACheckBox.Value = true;
            app.GapEditFieldLabel = uilabel(app.LeftPanel);
            app.GapEditFieldLabel.Text = 'Gap';
            app.GapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CutoffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.CutoffsetEditFieldLabel.Text = 'Cut offset';
            app.CutoffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CutoffsetEditField.Value = 150;
            app.ChannelselectLabel = uilabel(app.LeftPanel);
            app.ChannelselectLabel.Text = {'Channel'; 'select'};
            app.ChannelselectListBox = uilistbox(app.LeftPanel);
            app.ChannelselectListBox.Items = {'Ch1', 'Ch2', 'Ch3', 'Ch4', 'All'};
            app.ChannelselectListBox.ItemsData = {'1', '2', '3', '4', '5', ''};
            app.ChannelselectListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelselectListBoxValueChanged, true);
            app.ChannelselectListBox.Value = '5';
            app.BeamformingCheckBox = uicheckbox(app.LeftPanel);
            app.BeamformingCheckBox.ValueChangedFcn = createCallbackFcn(app, @BeamformingCheckBoxValueChanged, true);
            app.BeamformingCheckBox.Text = 'Beamforming';
            app.BeamformingCheckBox.Value = true;
            app.CutterCheckBox = uicheckbox(app.LeftPanel);
            app.CutterCheckBox.Text = 'Cutter';
            app.CutterCheckBox.Value = true;
            app.IQtoolsButton = uibutton(app.LeftPanel, 'push');
            app.IQtoolsButton.ButtonPushedFcn = createCallbackFcn(app, @IQtoolsButtonPushed, true);
            app.IQtoolsButton.Text = 'IQtools';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end