classdef iqampdepphase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AmplitudedependendphasecorrectionUIFigure  matlab.ui.Figure
        EnableCheckBox           matlab.ui.control.CheckBox
        ExponentSpinnerLabel     matlab.ui.control.Label
        ExponentSpinner          matlab.ui.control.Spinner
        AmpDepPhaseSpinnerLabel  matlab.ui.control.Label
        AmpDepPhaseSpinner       matlab.ui.control.Spinner
        AbsPhaseSpinnerLabel     matlab.ui.control.Label
        AbsPhaseSpinner          matlab.ui.control.Spinner
        GainSpinnerLabel         matlab.ui.control.Label
        GainSpinner              matlab.ui.control.Spinner
    end

    
    methods (Access = private)
        
        function doUpdate(app)
            enable = app.EnableCheckBox.Value;
            exponent = app.ExponentSpinner.Value;
            ampDepPhase = app.AmpDepPhaseSpinner.Value;
            absPhase = app.AbsPhaseSpinner.Value;
            gain = app.GainSpinner.Value;
            acs = load(iqampCorrFilename());
            acs.nonLinCorr.ampDepPhaseCorr = enable;
            acs.nonLinCorr.exponent = exponent;
            acs.nonLinCorr.ampDepPhase = ampDepPhase;
            acs.nonLinCorr.absPhase = absPhase;
            acs.nonLinCorr.gain = gain;
            save(iqampCorrFilename(), '-struct', 'acs');
        end
        
        function updateEnable(app)
            enable = app.EnableCheckBox.Value;
            if (enable)
                app.ExponentSpinner.Enable = 'on';
                app.AmpDepPhaseSpinner.Enable = 'on';
                app.AbsPhaseSpinner.Enable = 'on';
                app.GainSpinner.Enable = 'on';
            else
                app.ExponentSpinner.Enable = 'off';
                app.AmpDepPhaseSpinner.Enable = 'off';
                app.AbsPhaseSpinner.Enable = 'off';
                app.GainSpinner.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            acs = load(iqampCorrFilename());
            if (isfield(acs, 'nonLinCorr'))
                if (isfield(acs.nonLinCorr, 'ampDepPhaseCorr'))
                    app.EnableCheckBox.Value = acs.nonLinCorr.ampDepPhaseCorr;
                end
                if (isfield(acs.nonLinCorr, 'exponent'))
                    app.ExponentSpinner.Value = acs.nonLinCorr.exponent;
                end
                if (isfield(acs.nonLinCorr, 'ampDepPhase'))
                    app.AmpDepPhaseSpinner.Value = acs.nonLinCorr.ampDepPhase;
                end
                if (isfield(acs.nonLinCorr, 'absPhase'))
                    app.AbsPhaseSpinner.Value = acs.nonLinCorr.absPhase;
                end
                if (isfield(acs.nonLinCorr, 'gain'))
                    app.GainSpinner.Value = acs.nonLinCorr.gain;
                end
            end
            app.updateEnable();
            %app.AmplitudedependendphasecorrectionUIFigure.Parent
           
            movegui(app.AmplitudedependendphasecorrectionUIFigure,"center");
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            app.updateEnable();
            app.doUpdate();           
        end

        % Value changed function: ExponentSpinner
        function ExponentSpinnerValueChanged(app, event)
            app.doUpdate();
        end

        % Value changed function: AmpDepPhaseSpinner
        function AmpDepPhaseSpinnerValueChanged(app, event)
            app.doUpdate();
        end

        % Value changed function: AbsPhaseSpinner
        function AbsPhaseSpinnerValueChanged(app, event)
            app.doUpdate();
        end

        % Value changed function: GainSpinner
        function GainSpinnerValueChanged(app, event)
            app.doUpdate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AmplitudedependendphasecorrectionUIFigure = uifigure('Visible', 'off');
            app.AmplitudedependendphasecorrectionUIFigure.Name = 'Amplitude dependend phase correction';
            app.EnableCheckBox = uicheckbox(app.AmplitudedependendphasecorrectionUIFigure);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.ExponentSpinnerLabel = uilabel(app.AmplitudedependendphasecorrectionUIFigure);
            app.ExponentSpinnerLabel.Text = 'Exponent';
            app.ExponentSpinner = uispinner(app.AmplitudedependendphasecorrectionUIFigure);
            app.ExponentSpinner.Step = 0.1;
            app.ExponentSpinner.ValueChangedFcn = createCallbackFcn(app, @ExponentSpinnerValueChanged, true);
            app.ExponentSpinner.Value = 1;
            app.AmpDepPhaseSpinnerLabel = uilabel(app.AmplitudedependendphasecorrectionUIFigure);
            app.AmpDepPhaseSpinnerLabel.Text = 'Amp.Dep.Phase';
            app.AmpDepPhaseSpinner = uispinner(app.AmplitudedependendphasecorrectionUIFigure);
            app.AmpDepPhaseSpinner.Step = 0.1;
            app.AmpDepPhaseSpinner.ValueChangedFcn = createCallbackFcn(app, @AmpDepPhaseSpinnerValueChanged, true);
            app.AmpDepPhaseSpinner.Value = 1;
            app.AbsPhaseSpinnerLabel = uilabel(app.AmplitudedependendphasecorrectionUIFigure);
            app.AbsPhaseSpinnerLabel.Text = 'Abs.Phase';
            app.AbsPhaseSpinner = uispinner(app.AmplitudedependendphasecorrectionUIFigure);
            app.AbsPhaseSpinner.Step = 0.1;
            app.AbsPhaseSpinner.ValueChangedFcn = createCallbackFcn(app, @AbsPhaseSpinnerValueChanged, true);
            app.GainSpinnerLabel = uilabel(app.AmplitudedependendphasecorrectionUIFigure);
            app.GainSpinnerLabel.Text = 'Gain';
            app.GainSpinner = uispinner(app.AmplitudedependendphasecorrectionUIFigure);
            app.GainSpinner.Step = 0.1;
            app.GainSpinner.ValueChangedFcn = createCallbackFcn(app, @GainSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.AmplitudedependendphasecorrectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iqampdepphase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AmplitudedependendphasecorrectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AmplitudedependendphasecorrectionUIFigure)
        end
    end
end