classdef rxBoardControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rxBoardControlUIFigure  matlab.ui.Figure
        LoadRegsButton          matlab.ui.control.Button
        SaveRegsButton          matlab.ui.control.Button
        CFSpinner               matlab.ui.control.Spinner
        CFSpinnerLabel          matlab.ui.control.Label
        R2Spinner               matlab.ui.control.Spinner
        R2SpinnerLabel          matlab.ui.control.Label
        R1Spinner               matlab.ui.control.Spinner
        C1Spinner               matlab.ui.control.Spinner
        C1SpinnerLabel          matlab.ui.control.Label
        C2Spinner               matlab.ui.control.Spinner
        C2SpinnerLabel          matlab.ui.control.Label
        R3Spinner               matlab.ui.control.Spinner
        R3SpinnerLabel          matlab.ui.control.Label
        RM2Spinner              matlab.ui.control.Spinner
        RM2SpinnerLabel         matlab.ui.control.Label
        R1SpinnerLabel          matlab.ui.control.Label
        CM2Spinner              matlab.ui.control.Spinner
        CM2SpinnerLabel         matlab.ui.control.Label
        RMSpinner               matlab.ui.control.Spinner
        RMSpinnerLabel          matlab.ui.control.Label
        CMSpinner               matlab.ui.control.Spinner
        CMSpinnerLabel          matlab.ui.control.Label
        RX1_DAC_QSpinnerLabel   matlab.ui.control.Label
        RX1_DAC_ISpinnerLabel   matlab.ui.control.Label
        RX2_DAC_QSpinnerLabel   matlab.ui.control.Label
        RX2_DAC_ISpinnerLabel   matlab.ui.control.Label
        RX3_DAC_QSpinnerLabel   matlab.ui.control.Label
        RX3_DAC_ISpinnerLabel   matlab.ui.control.Label
        CBBSpinner              matlab.ui.control.Spinner
        RX1_DAC_QSpinner        matlab.ui.control.Spinner
        RX4_DAC_QSpinnerLabel   matlab.ui.control.Label
        CBBSpinnerLabel         matlab.ui.control.Label
        RX1_DAC_ISpinner        matlab.ui.control.Spinner
        RFSpinner               matlab.ui.control.Spinner
        RX2_DAC_QSpinner        matlab.ui.control.Spinner
        RFSpinnerLabel          matlab.ui.control.Label
        RX2_DAC_ISpinner        matlab.ui.control.Spinner
        VCSpinner               matlab.ui.control.Spinner
        RX3_DAC_QSpinner        matlab.ui.control.Spinner
        VCSpinnerLabel          matlab.ui.control.Label
        RX3_DAC_ISpinner        matlab.ui.control.Spinner
        PortListDropDown        matlab.ui.control.DropDown
        RX4_DAC_ISpinnerLabel   matlab.ui.control.Label
        RX4_DAC_QSpinner        matlab.ui.control.Spinner
        PortListDropDownLabel   matlab.ui.control.Label
        RX4_DAC_ISpinner        matlab.ui.control.Spinner
        ChoseportandpressthebuttonButton  matlab.ui.control.Button
    end


    properties (Access = public)
        Arduino = [];
        portList = [];
        port = [];
        readBack = [];
        path = fullfile(pwd,'Settings','registers.mat');
        registers = struct('RM2', 5, 'R3', 3, 'C2', 2, 'C1', 14, 'CM2', 2, ...
            'R1', 0, 'R2', 0, 'CF', 0, 'RF', 4, 'CBB', 15, 'CM', 4, 'RM', 2, ...
            'RX4_DAC_I', 32, 'RX4_DAC_Q', 32, 'RX3_DAC_I', 32, 'RX3_DAC_Q', 32, ...
            'RX2_DAC_I', 32, 'RX2_DAC_Q', 32, 'RX1_DAC_I', 32, 'RX1_DAC_Q', 32, 'VC', 1);
    end

    methods (Access = public)
        function updateRXboard(app)
            app.readBack = rxBoardControlF(app.registers, app.Arduino);
        end

        function pollButtons(app)
            app.registers.RM2 = app.RM2Spinner.Value;
            app.registers.R3 = app.R3Spinner.Value;
            app.registers.C2 = app.C2Spinner.Value;
            app.registers.C1 = app.C1Spinner.Value;
            app.registers.CM2 = app.CM2Spinner.Value;
            app.registers.R1 = app.R1Spinner.Value;
            app.registers.R2 = app.R2Spinner.Value;
            app.registers.CF = app.CFSpinner.Value;
            app.registers.RF = app.RFSpinner.Value;
            app.registers.CBB = app.CBBSpinner.Value;
            app.registers.CM = app.CMSpinner.Value;
            app.registers.RM = app.RMSpinner.Value;
            app.registers.RX4_DAC_I = app.RX4_DAC_ISpinner.Value;
            app.registers.RX4_DAC_Q = app.RX4_DAC_QSpinner.Value;
            app.registers.RX3_DAC_I = app.RX3_DAC_ISpinner.Value;
            app.registers.RX3_DAC_Q = app.RX3_DAC_QSpinner.Value;
            app.registers.RX2_DAC_I = app.RX2_DAC_ISpinner.Value;
            app.registers.RX2_DAC_Q = app.RX2_DAC_QSpinner.Value;
            app.registers.RX1_DAC_I = app.RX1_DAC_ISpinner.Value;
            app.registers.RX1_DAC_Q = app.RX1_DAC_QSpinner.Value;
            app.registers.VC = app.VCSpinner.Value;
        end

        function updateFields(app)
            app.RM2Spinner.Value = app.registers.RM2;
            app.R3Spinner.Value = app.registers.R3;
            app.C2Spinner.Value = app.registers.C2;
            app.C1Spinner.Value = app.registers.C1;
            app.CM2Spinner.Value = app.registers.CM2;
            app.R1Spinner.Value = app.registers.R1;
            app.R2Spinner.Value = app.registers.R2;
            app.CFSpinner.Value = app.registers.CF;
            app.RFSpinner.Value = app.registers.RF;
            app.CBBSpinner.Value = app.registers.CBB;
            app.CMSpinner.Value = app.registers.CM;
            app.RMSpinner.Value = app.registers.RM;
            app.RX4_DAC_ISpinner.Value = app.registers.RX4_DAC_I;
            app.RX4_DAC_QSpinner.Value = app.registers.RX4_DAC_Q;
            app.RX3_DAC_ISpinner.Value = app.registers.RX3_DAC_I;
            app.RX3_DAC_QSpinner.Value = app.registers.RX3_DAC_Q;
            app.RX2_DAC_ISpinner.Value = app.registers.RX2_DAC_I;
            app.RX2_DAC_QSpinner.Value = app.registers.RX2_DAC_Q;
            app.RX1_DAC_ISpinner.Value = app.registers.RX1_DAC_I;
            app.RX1_DAC_QSpinner.Value = app.registers.RX1_DAC_Q;
            app.VCSpinner.Value = app.registers.VC;
        end
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(mfilename('fullpath')))
            addpath(genpath([pwd '\Functions']))
            addpath(genpath([pwd '\Settings']))
            data = load(app.path);
            app.registers = data.registers;
            updateFields(app)
        end

        % Drop down opening function: PortListDropDown
        function PortListDropDownOpening(app, event)
            app.port = [];
            try
                app.portList = serialportlist("available");
            catch
                app.portList = [];
            end
            app.PortListDropDown.Items = app.portList;
        end

        % Callback function: PortListDropDown, PortListDropDown
        function PortListDropDownValueChanged(app, event)
            app.port = app.PortListDropDown.Value;
        end

        % Button pushed function: ChoseportandpressthebuttonButton
        function ChoseportandpressthebuttonButtonPushed(app, event)
            if isempty(app.Arduino)
                app.Arduino = serialport(app.port,115200);
                app.ChoseportandpressthebuttonButton.Text = [app.port " connected"];
                app.ChoseportandpressthebuttonButton.BackgroundColor = 'g';
            else
                clear app.Arduino
                app.Arduino = [];
                app.ChoseportandpressthebuttonButton.Text = "Disconnected";
                app.ChoseportandpressthebuttonButton.BackgroundColor = 'r';
            end
        end

        % Button pushed function: SaveRegsButton
        function SaveRegsButtonPushed(app, event)
            defaultFile = fullfile(pwd,'Settings','registers.mat');
            [baseFileName, folder] = uiputfile(defaultFile);
            if isequal(baseFileName,0) || isequal(folder,0)
                return; % user pressed Cancel
            end
            registers = app.registers;
            save(fullfile(folder, baseFileName), 'registers');
        end

        % Button pushed function: LoadRegsButton
        function LoadRegsButtonPushed(app, event)
            defaultPath = fullfile(pwd,'Settings','*.mat');
            [file, path] = uigetfile(defaultPath);
            if isequal(file,0) || isequal(path,0)
                return; % user pressed Cancel
            end
            data = load(fullfile(path,file));
            if isfield(data,'registers')
                app.registers = data.registers;
                updateFields(app);
                updateRXboard(app);
            else
                uialert(app.rxBoardControlUIFigure,'File does not contain registers','Load Error');
            end
        end

        % Value changed function: C1Spinner, C2Spinner, CBBSpinner, 
        % ...and 18 other components
        function SpinnerValueChanged(app, event)
            pollButtons(app);
            updateRXboard(app);
        end

        % Close request function: rxBoardControlUIFigure
        function rxBoardControlUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rxBoardControlUIFigure = uifigure('Visible', 'off');
            app.rxBoardControlUIFigure.Name = 'rxBoardControl';
            app.rxBoardControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @rxBoardControlUIFigureCloseRequest, true);
            app.ChoseportandpressthebuttonButton = uibutton(app.rxBoardControlUIFigure, 'push');
            app.ChoseportandpressthebuttonButton.ButtonPushedFcn = createCallbackFcn(app, @ChoseportandpressthebuttonButtonPushed, true);
            app.ChoseportandpressthebuttonButton.Text = 'Chose port and press the button';
            app.RX4_DAC_ISpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX4_DAC_ISpinner.Limits = [0 63];
            app.RX4_DAC_ISpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX4_DAC_ISpinner.Value = 32;
            app.PortListDropDownLabel = uilabel(app.rxBoardControlUIFigure);
            app.PortListDropDownLabel.Text = 'Port List';
            app.RX4_DAC_QSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX4_DAC_QSpinner.Limits = [0 63];
            app.RX4_DAC_QSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX4_DAC_QSpinner.Value = 32;
            app.RX4_DAC_ISpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX4_DAC_ISpinnerLabel.Text = 'RX4_DAC_I';
            app.PortListDropDown = uidropdown(app.rxBoardControlUIFigure);
            app.PortListDropDown.Items = {'Com'};
            app.PortListDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortListDropDownOpening, true);
            app.PortListDropDown.ValueChangedFcn = createCallbackFcn(app, @PortListDropDownValueChanged, true);
            app.PortListDropDown.ClickedFcn = createCallbackFcn(app, @PortListDropDownValueChanged, true);
            app.PortListDropDown.Value = 'Com';
            app.RX3_DAC_ISpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX3_DAC_ISpinner.Limits = [0 63];
            app.RX3_DAC_ISpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX3_DAC_ISpinner.Value = 32;
            app.VCSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.VCSpinnerLabel.Text = 'VC';
            app.RX3_DAC_QSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX3_DAC_QSpinner.Limits = [0 63];
            app.RX3_DAC_QSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX3_DAC_QSpinner.Value = 32;
            app.VCSpinner = uispinner(app.rxBoardControlUIFigure);
            app.VCSpinner.Limits = [0 1];
            app.VCSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.VCSpinner.Value = 1;
            app.RX2_DAC_ISpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX2_DAC_ISpinner.Limits = [0 63];
            app.RX2_DAC_ISpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX2_DAC_ISpinner.Value = 32;
            app.RFSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RFSpinnerLabel.Text = 'RF';
            app.RX2_DAC_QSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX2_DAC_QSpinner.Limits = [0 63];
            app.RX2_DAC_QSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX2_DAC_QSpinner.Value = 32;
            app.RFSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RFSpinner.Limits = [0 15];
            app.RFSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RFSpinner.Value = 4;
            app.RX1_DAC_ISpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX1_DAC_ISpinner.Limits = [0 63];
            app.RX1_DAC_ISpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX1_DAC_ISpinner.Value = 32;
            app.CBBSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.CBBSpinnerLabel.Text = 'CBB';
            app.RX4_DAC_QSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX4_DAC_QSpinnerLabel.Text = 'RX4_DAC_Q';
            app.RX1_DAC_QSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RX1_DAC_QSpinner.Limits = [0 63];
            app.RX1_DAC_QSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RX1_DAC_QSpinner.Value = 32;
            app.CBBSpinner = uispinner(app.rxBoardControlUIFigure);
            app.CBBSpinner.Limits = [0 31];
            app.CBBSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CBBSpinner.Value = 15;
            app.RX3_DAC_ISpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX3_DAC_ISpinnerLabel.Text = 'RX3_DAC_I';
            app.RX3_DAC_QSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX3_DAC_QSpinnerLabel.Text = 'RX3_DAC_Q';
            app.RX2_DAC_ISpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX2_DAC_ISpinnerLabel.Text = 'RX2_DAC_I';
            app.RX2_DAC_QSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX2_DAC_QSpinnerLabel.Text = 'RX2_DAC_Q';
            app.RX1_DAC_ISpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX1_DAC_ISpinnerLabel.Text = 'RX1_DAC_I';
            app.RX1_DAC_QSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RX1_DAC_QSpinnerLabel.Text = 'RX1_DAC_Q';
            app.CMSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.CMSpinnerLabel.Text = 'CM';
            app.CMSpinner = uispinner(app.rxBoardControlUIFigure);
            app.CMSpinner.Limits = [0 15];
            app.CMSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CMSpinner.Value = 4;
            app.RMSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RMSpinnerLabel.Text = 'RM';
            app.RMSpinner = uispinner(app.rxBoardControlUIFigure);
            app.RMSpinner.Limits = [0 15];
            app.RMSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RMSpinner.Value = 2;
            app.CM2SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.CM2SpinnerLabel.Text = 'CM2';
            app.CM2Spinner = uispinner(app.rxBoardControlUIFigure);
            app.CM2Spinner.Limits = [0 7];
            app.CM2Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CM2Spinner.Value = 2;
            app.R1SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.R1SpinnerLabel.Text = 'R1';
            app.RM2SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.RM2SpinnerLabel.Text = 'RM2';
            app.RM2Spinner = uispinner(app.rxBoardControlUIFigure);
            app.RM2Spinner.Limits = [0 15];
            app.RM2Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RM2Spinner.Value = 5;
            app.R3SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.R3SpinnerLabel.Text = 'R3';
            app.R3Spinner = uispinner(app.rxBoardControlUIFigure);
            app.R3Spinner.Limits = [0 7];
            app.R3Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.R3Spinner.Value = 3;
            app.C2SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.C2SpinnerLabel.Text = 'C2';
            app.C2Spinner = uispinner(app.rxBoardControlUIFigure);
            app.C2Spinner.Limits = [0 7];
            app.C2Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.C2Spinner.Value = 2;
            app.C1SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.C1SpinnerLabel.Text = 'C1';
            app.C1Spinner = uispinner(app.rxBoardControlUIFigure);
            app.C1Spinner.Limits = [0 31];
            app.C1Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.C1Spinner.Value = 14;
            app.R1Spinner = uispinner(app.rxBoardControlUIFigure);
            app.R1Spinner.Limits = [0 1];
            app.R1Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.R2SpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.R2SpinnerLabel.Text = 'R2';
            app.R2Spinner = uispinner(app.rxBoardControlUIFigure);
            app.R2Spinner.Limits = [0 1];
            app.R2Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CFSpinnerLabel = uilabel(app.rxBoardControlUIFigure);
            app.CFSpinnerLabel.Text = 'CF';
            app.CFSpinner = uispinner(app.rxBoardControlUIFigure);
            app.CFSpinner.Limits = [0 7];
            app.CFSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.SaveRegsButton = uibutton(app.rxBoardControlUIFigure, 'push');
            app.SaveRegsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRegsButtonPushed, true);
            app.SaveRegsButton.Text = 'Save Regs';
            app.LoadRegsButton = uibutton(app.rxBoardControlUIFigure, 'push');
            app.LoadRegsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegsButtonPushed, true);
            app.LoadRegsButton.Text = 'Load Regs';
            % Show the figure after all components are created
            app.rxBoardControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rxBoardControl

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.rxBoardControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.rxBoardControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rxBoardControlUIFigure)
        end
    end
end